classdef TuningScurves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        CSlider       matlab.ui.control.Slider
        CSliderLabel  matlab.ui.control.Label
        YSlider       matlab.ui.control.Slider
        YSliderLabel  matlab.ui.control.Label
        KSlider       matlab.ui.control.Slider
        KSliderLabel  matlab.ui.control.Label
        LSlider       matlab.ui.control.Slider
        LSliderLabel  matlab.ui.control.Label
        RightPanel    matlab.ui.container.Panel
        UIAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CSlider, KSlider, LSlider, YSlider
        function SliderValueChanged(app, event)


            clc
            C = app.CSlider.Value
            k = app.KSlider.Value
            y = app.YSlider.Value
            L = app.LSlider.Value
            k = -1/k;

            data = [7.583442601069386, 2.612564088471534
                21.0720538932029, 1.646836208317957
                54.82243665742799, 0.9812582932248768
                13.47035638293697, 2.0242299568948594
                32.67216538555153, 1.30937556433187
                6.300888013002208, 2.9644088157471162];

            airflow = linspace(0,200);
            Scurve = L./(1+exp(-k*(airflow-y)))+ C;


            plot(app.UIAxes,airflow,Scurve)
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            scatter(app.UIAxes,data(:,1),data(:,2))
            app.UIAxes.XLim = [-50 200];
            app.UIAxes.YLim = [0 4];
            hold(app.UIAxes,'off');

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LSliderLabel = uilabel(app.LeftPanel);
            app.LSliderLabel.Text = 'L';
            app.LSlider = uislider(app.LeftPanel);
            app.LSlider.Limits = [5 9];
            app.LSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LSlider.Value = 6.69;
            app.KSliderLabel = uilabel(app.LeftPanel);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.LeftPanel);
            app.KSlider.Limits = [5 20];
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.KSlider.Value = 12.46;
            app.YSliderLabel = uilabel(app.LeftPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.LeftPanel);
            app.YSlider.Limits = [-4 -1];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.YSlider.Value = -1.8189;
            app.CSliderLabel = uilabel(app.LeftPanel);
            app.CSliderLabel.Text = 'C';
            app.CSlider = uislider(app.LeftPanel);
            app.CSlider.Limits = [0.8 1.1];
            app.CSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.CSlider.Value = 0.8;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TuningScurves

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end